classdef climatewedges < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        WedgesEditFieldLabel          matlab.ui.control.Label
        WedgesEditField               matlab.ui.control.EditField
        SubmitButton                  matlab.ui.control.Button
        ConstraintsEditFieldLabel     matlab.ui.control.Label
        ConstraintsEditField          matlab.ui.control.EditField
        StrategyEditFieldLabel        matlab.ui.control.Label
        StrategyEditField             matlab.ui.control.EditField
        Lamp                          matlab.ui.control.Lamp
        Lamp2                         matlab.ui.control.Lamp
        Lamp2_2                       matlab.ui.control.Lamp
        progressLabel                 matlab.ui.control.Label
        TotalAvalibleWedgesPanel      matlab.ui.container.Panel
        instructionLabel              matlab.ui.control.Label
        Lamp2_3                       matlab.ui.control.Lamp
        HistoricalDataEditFieldLabel  matlab.ui.control.Label
        HistoricalDataEditField       matlab.ui.control.EditField
        CurrentStateLabel             matlab.ui.control.Label
        StrategySelect                matlab.ui.control.CheckBox
        StrategiesListBox             matlab.ui.control.ListBox
        selectedStrategiesBox         matlab.ui.control.ListBox
        AddButton                     matlab.ui.control.Button
        DeleteButton                  matlab.ui.control.Button
        StrategiesLabel               matlab.ui.control.Label
        SelectedStrategiesLabel       matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        EmissionsPlotTab              matlab.ui.container.Tab
        MainPlot                      matlab.ui.control.UIAxes
        MassofCO2PlotTab              matlab.ui.container.Tab
        SecPlot                       matlab.ui.control.UIAxes
        AverageWarmingPlotTab         matlab.ui.container.Tab
        TerPlot                       matlab.ui.control.UIAxes
        WhatHappensIfTab              matlab.ui.container.Tab
        QuarPlot                      matlab.ui.control.UIAxes
        ConstraintsPanel              matlab.ui.container.Panel
        ModifyStartButton             matlab.ui.control.Button
        StartYear                     matlab.ui.control.NumericEditField
        TotalCostofStrategyLabel      matlab.ui.control.Label
        Cost                          matlab.ui.control.NumericEditField
        SaveCurrentStrategyButton     matlab.ui.control.Button
        outputPath                    matlab.ui.control.EditField
        ClearButton                   matlab.ui.control.Button
        Lambda                        matlab.ui.control.NumericEditField
        SetLambdavalueButton          matlab.ui.control.Button
        CurrentWedgePanel             matlab.ui.container.Panel
        DescriptionLabel              matlab.ui.control.Label
        NeedsLabel                    matlab.ui.control.Label
        ChallengesLabel               matlab.ui.control.Label
        currDescription               matlab.ui.control.Label
        currNeeds                     matlab.ui.control.Label
        currChallenges                matlab.ui.control.Label
        StartYearLabel                matlab.ui.control.Label
        currStart                     matlab.ui.control.Label
        WedgesNescesaryToPanel        matlab.ui.container.Panel
        FlatineemmissionsLabel        matlab.ui.control.Label
        ReduceemissionstoLabel        matlab.ui.control.Label
        ReduceemissionstoLabel_2      matlab.ui.control.Label
        IfstartedintheyearLabel       matlab.ui.control.Label
        wedgeCalcYear                 matlab.ui.control.NumericEditField
        slevelsLabel                  matlab.ui.control.Label
        flatCheck                     matlab.ui.control.CheckBox
        nineCheck                     matlab.ui.control.CheckBox
        of1990slevelsLabel            matlab.ui.control.Label
        thirdCheck                    matlab.ui.control.CheckBox
        flatYear                      matlab.ui.control.NumericEditField
        nineYear                      matlab.ui.control.NumericEditField
        eightyYear                    matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        TotSectlab = 0;
        TotCoslab = 0;
        TotIDlab = 0;
        TotDescriptlab = 0;
        TotStratNamelab = 0;
        wedgeData = 0;
        constraintsData = 0;
        strategyData = 0;
        historicalData = 0;
        selectedWedges = 0;
        startYear = 2018;
        GtC_est = 0;
        CO2=0;
        lambda = .7;
        GtC_plots=  [];
        o_year = 0;
        GtC_oldest = 0;
        blankWedgeStruct = [];
        M = 1;
        GTemps = []
        usedStrategies = []
    end
    
    methods (Access = private)
        
        function output = readInFile(app,filepath,index)
            file = fopen(filepath,'r');
            fileData = {};
            header = fgetl(file);
            if index == 1
                delim = ';';
            elseif index == 4
                delim = '	';
            else
                delim = ' ';
            end
            header_p = strsplit(header,delim);
            temp_output = cell(1,2*length(header_p));
            temp_output(1:2:end) = header_p;
            if index == 4
                output = struct('Year',[],'GtCyr',[]);
            else
                output = struct(temp_output{1:end});
                
                if index == 1
                    app.selectedWedges = output;
                    app.selectedWedges.startYear = [];
                    app.blankWedgeStruct = output
                end
            end
            fields = fieldnames(output);
            while ~feof(file)
                curr_line = fgetl(file);
                curr_line_p = strsplit(curr_line,delim);
                
                for i = 1:length(curr_line_p)
                    if isempty(output.(fields{i}))
                        output.(fields{i}) = curr_line_p(i);
                    else
                        output.(fields{i})(end+1) = curr_line_p(i);
                    end
                end
            end
        end
        
        function plotWarming(app)
            
            app.TerPlot.XLim = [2000,2068];
            years = 2000:1:2068;
            Temps = []
            F = @(x) 5.35 * log(x./278);
            T = @(x) app.lambda * F(x);
            for i = 1:length(years)
                Temps(end+1) = T(app.CO2(i));
            end
            plot(app.TerPlot,years,Temps);
            app.GTemps = Temps
        end
        
        function plotWedges(app)
            
            slope = (app.GtC_est(end)-(app.GtC_est(1)))./ 50;
            f =  @(x)  slope.*x - (slope*2018) + app.GtC_est(1);
            cla(app.MainPlot);
            app.MainPlot.XLim = [1959,2068];
            plotHData(app);
            plotFlatline(app);
            GtC_old = [app.GtC_est(1),app.GtC_est(end)]
            for i = 1:length(app.selectedWedges.ID)
                
                years = [app.selectedWedges.startYear(i),2068];
                c_year = app.selectedWedges.startYear(i)
                percentage = (2068-app.selectedWedges.startYear(i))./50
                
                if i == 1
                    GtC_new = [f(c_year),f(2068)-(i*percentage)]
                else
                    p_year = app.selectedWedges.startYear(i-1)
                    if p_year <= c_year
                        
                        slope2 = (GtC_old(end)-GtC_old(1))./ (2068-p_year)
                        g = @(x) slope2.*x - (slope2*p_year) + GtC_old(1);
                        GtC_new = [g(c_year),g(2068)-(1*percentage)];
                    else
                        for j = 1:i-1
                            if app.selectedWedges.startYear(j) <= c_year
                                app.o_year = app.selectedWedges.startYear(j)
                                app.GtC_oldest = app.GtC_plots(j,:);
                                slope3 = (app.GtC_oldest(end)-app.GtC_oldest(1))./ (2068-app.o_year)
                                h = @(x) slope3.*x - (slope3*app.o_year) + app.GtC_oldest(1);
                                
                                GtC_new = [h(c_year),GtC_old(end)-(1*percentage)];
                            end
                        end
                        
                        if app.o_year == 0
                            GtC_new = [f(c_year),GtC_old(end)-(1*percentage)]
                        end
                        
                    end
                    
                    
                end
                app.GtC_plots = [app.GtC_plots;GtC_new];
                %GtC_new = [GtC_new(1),GtC_new(end)]
                plot(app.MainPlot,years,GtC_new);
                GtC_comb = [[GtC_new(1),GtC_old(end)],fliplr(GtC_new)];
                years_comb = [years,fliplr(years)]
                if i == 1
                    if strcmp(app.selectedWedges.Category{i},'Efficiency and Conservation')
                        fill(app.MainPlot,years_comb,GtC_comb,'y')
                    elseif strcmp(app.selectedWedges.Category{i},'Fossil Fuel')
                        fill(app.MainPlot,years_comb,GtC_comb,'b')
                    elseif strcmp(app.selectedWedges.Category{i},'Renewables and Biostorage')
                        fill(app.MainPlot,years_comb,GtC_comb,'g')
                    else
                        fill(app.MainPlot,years_comb,GtC_comb,'r')
                    end
                    
                else
                    p_year = app.selectedWedges.startYear(i-1)
                    if p_year <= c_year
                        if strcmp(app.selectedWedges.Category{i},'Efficiency and Conservation')
                            fill(app.MainPlot,years_comb,GtC_comb,'y')
                        elseif strcmp(app.selectedWedges.Category{i},'Fossil Fuel')
                            fill(app.MainPlot,years_comb,GtC_comb,'b')
                        elseif strcmp(app.selectedWedges.Category{i},'Renewables and Biostorage')
                            fill(app.MainPlot,years_comb,GtC_comb,'g')
                        else
                            fill(app.MainPlot,years_comb,GtC_comb,'r')
                        end
                        
                    else
                        new_years = [app.o_year,p_year]
                        years_comb2 = [new_years,fliplr(new_years)]
                        if app.o_year == 0;
                            GtC_comb2 = [[app.GtC_oldest(end),GtC_new(1)],fliplr([app.GtC_oldest(end),GtC_new(1)])];
                        else
                            GtC_comb2 = [[app.GtC_oldest(end),GtC_new(1)],fliplr([app.GtC_oldest(end),GtC_new(1)])];
                        end
                        
                        if strcmp(app.selectedWedges.Category{i},'Efficiency and Conservation')
                            fill(app.MainPlot,years_comb,GtC_comb,'y')
                            %fill(app.MainPlot,years_comb2,GtC_comb2,'y')
                        elseif strcmp(app.selectedWedges.Category{i},'Fossil Fuel')
                            fill(app.MainPlot,years_comb,GtC_comb,'b')
                            %fill(app.MainPlot,years_comb2,GtC_comb2,'b')
                        elseif strcmp(app.selectedWedges.Category{i},'Renewables and Biostorage')
                            fill(app.MainPlot,years_comb,GtC_comb,'g')
                            %fill(app.MainPlot,years_comb2,GtC_comb2,'g')
                        else
                            fill(app.MainPlot,years_comb,GtC_comb,'r')
                            %fill(app.MainPlot,years_comb2,GtC_comb2,'v')
                        end
                        
                    end
                    
                end
                GtC_old=GtC_new;
            end
            app.GtC_plots =  []
        end
        
        function addStrategy(app)
            
            for i = 1:length(app.strategyData.ID)
                %Default Start Year
                
                startYear = str2num(app.strategyData.YearStart{i})
                ID = str2num(app.strategyData.ID{i})
                fields = fieldnames(app.selectedWedges)
                c_wedge = app.wedgeData.StrategyName{ID};
                for j = 1:length(fields)-1
                    app.selectedWedges.(fields{j}){end+1} = app.wedgeData.(fields{j}){ID}
                end
                app.selectedWedges.startYear(end+1) = startYear
                if ismember(c_wedge,app.selectedStrategiesBox.Items)
                    counter = 1;
                    for k = 1:length(app.selectedStrategiesBox.Items)
                        try
                            if isequal(selected,app.selectedStrategiesBox.Items{k}(1:length(c_wedge)))
                                counter = counter+1;
                            end
                        catch
                        end
                    end
                    c_wedge= cat(2,c_wedge,num2str(counter));
                end
                app.selectedStrategiesBox.Items = cat(2,app.selectedStrategiesBox.Items,c_wedge)
                plotWedges(app)          %%-EVP- Is this a current state fxn call?, Using it later.
                checkConstraints(app)
                sumCost(app)
            end
            
            
        end
        
        function info = updateInfo(app)
            SSect = app.selectedWedges.Sector;
            SCos = app.selectedWedges.Cost;
            SIDs = app.selectedWedges.ID;
            SCats = app.selectedWedges.Category;
            SDescript = app.selectedWedges.Description;
            SStratName = app.selectedWedges.StrategyName;
            if app.M == 1;
                %app.TotSectlab = uilabel(app.TotalCast,'Text',SSect);
                app.TotCoslab = uilabel(app.TotalCast,'Text',SCos);
                app.TotIDlab  = uilabel(app.TotalCast,'Text',SIDs);
                %TotCatslab = uilabel(app.TotalCast,'Text',SCats);
                app.TotDescriptlab = uilabel(app.TotalCast,'Text',SDescript);
                app.TotStratNamelab = uilabel(app.TotalCast,'Text',SStratName);
                %app.TotSectlab.Position = [22 5 90 40];
                app.TotCoslab.Position = [12 10 10 40];
                app.TotIDlab.Position = [5 0 10 40];
                %lab.Position = [ ];
                app.TotDescriptlab.Position = [60 70 90 16];
                app.TotStratNamelab.Position = [28 5 90 150];
                app.M = app.M +1;
            else
                %app.TotSectlab.Text = '';
                app.TotCoslab.Text = '';
                app.TotIDlab.Text= '';
                app.TotDescriptlab.Text= '';
                app.TotStratNamelab.Text= '';
                %app.TotSectlab = uilabel(app.TotalCast,'Text',SSect);
                app.TotCoslab = uilabel(app.TotalCast,'Text',SCos);
                app.TotIDlab  = uilabel(app.TotalCast,'Text',SIDs);
                %TotCatslab = uilabel(app.TotalCast,'Text',SCats);
                app.TotDescriptlab = uilabel(app.TotalCast,'Text',SDescript);
                app.TotStratNamelab = uilabel(app.TotalCast,'Text',SStratName);
                %app.TotSectlab.Position = [20 5 90 40];
                app.TotCoslab.Position = [5 5 10 10];
                app.TotIDlab.Position = [10 5 10 25*5];
                %lab.Position = [ ];
                app.TotDescriptlab.Position = [40 -60 150 16];
                app.TotStratNamelab.Position = [27 5 90 30];
                app.M = app.M+1;
            end
            
        end
        
    end   %%an end for methods.
    
    methods (Access = public)
        
        function writeWedges(app,path)
            try
                file  = fopen(path,'wt');
                header = 'ID;StrategyName;Sector;NumberUsed;TotalCost;Challenges\n';
                fprintf(file,header);
                currentID = NaN;
                duplicates = [];
                %Filters out duplicate wedges
                for i = 1:length(app.selectedWedges.ID)
                    if ismember(app.selectedWedges.ID{i},app.selectedWedges.ID)
                        if isempty(duplicates)
                            duplicates = [duplicates;[str2num(app.selectedWedges.ID{i}),1]]
                        else
                            curr = find(duplicates == str2num(app.selectedWedges.ID{i}))
                            if isempty(curr)
                                duplicates = [duplicates;[str2num(app.selectedWedges.ID{i}),1]]
                            else
                                d_array = duplicates(curr,:)
                                duplicates(curr,:) = [d_array(1),d_array(2)+1];
                            end
                            
                        end
                        
                    end
                end
                %Writes each wedge + data to file
                for i = 1:length(duplicates)
                    j = duplicates(i)
                    line = cat(2,app.wedgeData.ID{j},';',app.wedgeData.StrategyName{j},';',app.wedgeData.Sector{j},';',num2str(duplicates(i,end)),';',num2str(str2num(app.wedgeData.Cost{j}) * duplicates(i,end)),';' ,app.wedgeData.Challenges{j},'\n');
                    fprintf(file,line);
                end
                fclose(file);
            catch
                error = (msgbox('Error: Invalid output path','Success','modal'));
            end
            success = (msgbox('Success: The current strategies were saved to the specified file','Success','modal'));
            
        end
        
        function plotHData(app)
            %Imported Historical Data
            app.MainPlot.XLim = [1959,2068];
            years1= cellfun(@str2double,app.historicalData.Year)
            GtCyr =cellfun(@str2double,app.historicalData.GtCyr)
            %Estimation of future emissions
            years2_temp = [2018,2068];
            years2 = 2018:1:2068;
            app.GtC_est = [];
            t_prev =2017;
            %C_init = 800;
            E_init = 10;
            E= @(x) E_init * 2^((x-t_prev)./50);
            %C= @(x) C_init + (.5*E(x));
            for i = 1:length(years2)
                app.GtC_est(end+1) = E(years2(i));
                E_init = app.GtC_est(end);
                
            end
            years_cat = cat(2,years1,years2_temp);
            GtC_est_cat = cat(2,GtCyr,[app.GtC_est(1),app.GtC_est(end)]);
            plot(app.MainPlot,years_cat,GtC_est_cat);
            
            
        end
        
        function plotFlatline(app)
            years = app.startYear:1:2068;
            init_value = app.GtC_est(app.startYear-2017);
            GtC_flatline = ones(size(years)).*init_value;
            plot(app.MainPlot,years,GtC_flatline);
        end
        
        function plotCO2(app)
            app.SecPlot.XLim = [2000,2068];
            years = 2000:1:2068;
            app.CO2 = []
            C_init = 800;
            %C02 for 2000-2017
            yearsFirst= cellfun(@str2double,app.historicalData.Year)
            GtCyrFirst =cellfun(@str2double,app.historicalData.GtCyr)
            index = find(yearsFirst == 2000);
            GtCyr_short = GtCyrFirst(index:end);
            for i = 1:18
                app.CO2(end+1) = C_init + (.5 * GtCyr_short(i));
                C_init = app.CO2(i);
            end
            
            %CO2 for 2018-2068
            for i = 1:51
                app.CO2(end+1) = C_init + (.5 * app.GtC_est(i));
                C_init = app.CO2(18+i);
            end
            %Convert from GtC to ppm with conversion factor 2.1
            app.CO2 = app.CO2./(2.1);
            plot(app.SecPlot,years,app.CO2)
        end
        
        function valid = checkConstraints(app)
            valid = true;
            app.usedStrategies = []
            if ~isempty(app.selectedWedges.ID)
                for i = 1:length(app.constraintsData.Sector)
                    counter = 0
                    for j = 1:length(app.selectedWedges.ID)
                        if strcmp(app.selectedWedges.Sector{j},app.constraintsData.Sector{i})
                            counter = counter + 1
                        elseif contains(app.selectedWedges.Sector{j},',')
                            sectors1 = strsplit(app.selectedWedges.Sector{j},',')
                            for k = 1:length(sectors1)
                                if strcmp(sectors1(k),app.constraintsData.Sector{i})
                                    counter = counter + 1
                                end
                            end
                        end
                        
                    end
                    app.usedStrategies =  [app.usedStrategies;[i,counter]];
                    if counter > str2num(app.constraintsData.Constraints{i})
                        valid = false
                        selected = app.selectedStrategiesBox.Value
                        s_index = length(app.selectedStrategiesBox.Items);
                        current_vals = get(app.selectedStrategiesBox,'Items')
                        current_vals(s_index) = []
                        app.selectedStrategiesBox.Items = current_vals
                        fields = fieldnames(app.selectedWedges)
                        if length(app.selectedWedges.ID) == 1
                            for i = 1:length(fields)-1
                                app.selectedWedges.(fields{i}) = [] ;
                            end
                            app.selectedWedges.startYear = []
                            
                        else
                            for i = 1:length(fields)-1
                                app.selectedWedges.(fields{i})(s_index) = [] ;
                            end
                            app.selectedWedges.startYear(s_index) = []
                        end
                        plotWedges(app);
                        error = (msgbox('Error: Current selection violates constraints','Error','modal'));
                        
                        break
                        
                    end


                end
                for i = 1:length(app.selectedWedges.ID)
                    occurances = find(strcmp(app.selectedWedges.ID(i),app.selectedWedges.ID))
                    if length(occurances) > (.5*length(app.selectedWedges.ID)) && length(app.selectedWedges.ID) > 1
                        valid = false
                        selected = app.selectedStrategiesBox.Value
                        s_index = length(app.selectedStrategiesBox.Items);
                        current_vals = get(app.selectedStrategiesBox,'Items')
                        current_vals(s_index) = []
                        app.selectedStrategiesBox.Items = current_vals
                        fields = fieldnames(app.selectedWedges)
                        if length(app.selectedWedges.ID) == 1
                            for j = 1:length(fields)-1
                                app.selectedWedges.(fields{j}) = [] ;
                            end
                            app.selectedWedges.startYear = []
                            
                        else
                            for j = 1:length(fields)-1
                                app.selectedWedges.(fields{j})(s_index) = [] ;
                            end
                            app.selectedWedges.startYear(s_index) = []
                        end
                        plotWedges(app);
                        error = (msgbox('Error: Current selection violates constraints','Error','modal'));
                        
                        break
                        
                    end
                end
                
            end


        end
        
        function sumCost(app)
            counter = 0;
            for i = 1:length(app.selectedWedges.Cost)
                counter = counter + str2num(app.selectedWedges.Cost{i})
            end
            app.Cost.Value = counter;
        end


        function dispCurrMeta(app)
            if isempty(app.selectedStrategiesBox.Items)
                
                app.currDescription.Text = '';
                app.currNeeds.Text = ''
                app.currChallenges.Text = ''
                app.currStart.Text = ''
                
            else
                f = figure('Visible','off')
                selected = app.selectedStrategiesBox.Value
                s_index = find(strcmp(app.selectedStrategiesBox.Items,selected));
                
                t_wrapper1 = uicontrol('Style','Text','Position',[11 239 225 46]);
                t_wrapped1 = textwrap(t_wrapper1,app.selectedWedges.Description(s_index))
                app.currDescription.Text = t_wrapped1;
                
                t_wrapper2 = uicontrol('Style','Text','Position',[11 169 225 46])
                t_wrapped2 = textwrap(t_wrapper2,app.selectedWedges.Needs(s_index));
                app.currNeeds.Text = t_wrapped2
                
                t_wrapper3 = uicontrol('Style','Text','Position',[8 90 225 46])
                t_wrapped3 = textwrap(t_wrapper3,app.selectedWedges.Challenges(s_index));
                app.currChallenges.Text = t_wrapped3;
                
                app.currStart.Text = num2str(app.selectedWedges.startYear(s_index));
            end
            
            
        end
        
        function plotFun(app)
            app.SecPlot.XLim = [2001,2068];
            %Proportionality constant for temperature rise to sea level rise (mm/year)
            a = 3.4
            
            dH = @(x) a*(app.GTemps(2:end)-app.GTemps(1));
            H = integral(dH,2000,2068,'ArrayValued',true);
            H = H./10
            years = 2001:2068
            yyaxis(app.QuarPlot,'right')
            plot(app.QuarPlot,years,H)
            
             yyaxis(app.QuarPlot,'left')
            plot(app.QuarPlot,years,app.GTemps(2:end))
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            x=2;
            app.StrategiesListBox.Items = {};
            app.selectedStrategiesBox.Items = {};
            app.Lamp.Color = [ 1 0 0];
            app.Lamp2.Color = [ 1 0 0];
            app.Lamp2_2.Color = [1 0 0 ];
            app.Lamp2_3.Color = [1 0 0 ];
            hold(app.MainPlot);
        
            app.MainPlot.Visible = 'off';
            app.SecPlot.Visible = 'off';
            app.TerPlot.Visible = 'off';
            app.AddButton.Visible = 'off';
            app.DeleteButton.Visible = 'off';
          %  app.StrategiesTypeBox.Visible = 'off';
            %app.TabGroup.Visible = 'off';
            app.ConstraintsPanel.Visible   = 'off';
            app.TotalAvalibleWedgesPanel.Visible = 'off';
        end

        % Value changed function: WedgesEditField
        function WedgesEditFieldValueChanged(app, event)
            wedge = app.WedgesEditField.Value;
            
        end

        % Value changed function: ConstraintsEditField
        function ConstraintsEditFieldValueChanged(app, event)
            constraints = app.ConstraintsEditField.Value;
            
        end

        % Value changed function: StrategyEditField
        function StrategyEditFieldValueChanged(app, event)
            strategy = app.StrategyEditField.Value;
            
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            fail_p=  1;
            try
                if app.StrategySelect.Value == 1
                    fpaths = {app.WedgesEditField.Value;app.ConstraintsEditField.Value;app.StrategyEditField.Value;app.HistoricalDataEditField.Value};
                    fail_p=  1;
                    app.wedgeData = app.readInFile(fpaths{1},1);
                    fail_p=  2;
                    app.constraintsData = app.readInFile(fpaths{2},2);
                    fail_p=  3;
                    app.strategyData = app.readInFile(fpaths{3},3);
                    fail_p = 4;
                    app.historicalData = app.readInFile(fpaths{4},4);
                    
                    app.Lamp.Color = [0 1 0];
                    app.Lamp2.Color = [0 1 0];
                    app.Lamp2_2.Color = [0 1 0];
                    app.Lamp2_3.Color = [0 1 0];
                else
                    fpaths = {app.WedgesEditField.Value;app.ConstraintsEditField.Value;app.HistoricalDataEditField.Value};
                    fail_p=  1;
                    app.wedgeData = app.readInFile(fpaths{1},1);
                    fail_p=  2;
                    app.constraintsData = app.readInFile(fpaths{2},2);
                    fail_p=  3;
                    app.historicalData = app.readInFile(fpaths{3},4);
                    fail_p=  4;
                    app.Lamp.Color = [0 1 0];
                    app.Lamp2.Color = [0 1 0];
                    app.Lamp2_2.Color = [0 1 0];
                    
                end
                success = (msgbox('Success: All files read in','Success','modal'));
 %               app.TotalCast.Visible = 'on';
                app.AddButton.Visible = 'on';
                app.DeleteButton.Visible = 'on';
                %app.StrategiesTypeBox.Visible = 'on';
                app.TabGroup.Visible = 'on';
                app.ConstraintsPanel.Visible   = 'on';
                app.TotalAvalibleWedgesPanel.Visible = 'on';
                app.MainPlot.Visible = 'on';
                app.SecPlot.Visible = 'on';
                app.TerPlot.Visible = 'on';


                CIDs = app.constraintsData.Constraints;
                CSect = app.constraintsData.Sector;
                Cos = app.wedgeData.Cost;
                IDs = app.wedgeData.ID;
                Cats = app.wedgeData.Category;
                Strats = app.wedgeData.StrategyName;
                
%                 SSect = app.selectedWedges.Sector;
%                 SCos = app.selectedWedges.Cost;
%                 SIDs = app.selectedWedges.ID;
%                 SCats = app.selectedWedges.Category;
%                 SDescript = app.selectedWedges.Description;
%                 SStratName = app.selectedWedges.StrategyName;
%                 
%                 TotSectlab = uilabel(app.TotalCast,'Text',SSect);
%                 TotCoslab = uilabel(app.TotalCast,'Text',SCos);
%                 TotIDlab  = uilabel(app.TotalCast,'Text',SIDs);
%                 %TotCatslab = uilabel(app.TotalCast,'Text',SCats);
%                 TotDescriptlab = uilabel(app.TotalCast,'Text',SDescript);
%                 TotStratNamelab = uilabel(app.TotalCast,'Text',SStratName);
%                         TotSectlab.Position = [20 5 90 40];
%                         TotCoslab.Position = [10 5 10 10];
%                         TotIDlab.Position = [10 5 10 25*5];
%                         %lab.Position = [ ];
%                         TotDescriptlab.Position = [40 -60 150 16];
%                         TotStratNamelab.Position = [27 5 90 30];
                
                CSectlabel = uilabel(app.ConstraintsPanel,'Text',CSect);
                CIDlabel =   uilabel(app.ConstraintsPanel,'Text',CIDs);
                CIDlabel.Position = [10 5 10 40];
                CSectlabel.Position = [20 5 90 40];
                
                app.StrategiesListBox.Items = Strats;
                app.instructionLabel.Text = 'Now, select strategies from the left';
                app.progressLabel.Text = '2) Construct a Strategy';
                disp(app.wedgeData)
                
                app.SubmitButton.Text = 'Go back';
                app.SubmitButton.Visible = 'off';
                
                numIDs = numel(IDs);
                numCost = numel(Cos);
                IDlabel = uilabel(app.TotalAvalibleWedgesPanel,'Text',IDs);
                IDlabel_h = uilabel(app.TotalAvalibleWedgesPanel, 'Text', 'IDs');
                Costlabel = uilabel(app.TotalAvalibleWedgesPanel, 'Text',Cos);
                Costlabel_h = uilabel(app.TotalAvalibleWedgesPanel, 'Text', 'Costs');
                IDlabel.Position = [6 -160 8 25*numIDs];
                IDlabel_h.Position(1:2) = [5 275];
                Costlabel.Position = [30 -160 15 25*numCost];
                Costlabel_h.Position(1:2) = [23 275];
                Stratslabel = uilabel(app.TotalAvalibleWedgesPanel, 'Text', Strats);
                Stratslabel_h = uilabel(app.TotalAvalibleWedgesPanel, 'Text','Strategy Name')
                Stratslabel.Position = [60 -160 115 25*numel(Cats)];
                Stratslabel_h.Position = [60 269 98 20];


                plotHData(app);
                plotFlatline(app);
                plotCO2(app);
                plotWarming(app);
                plotFun(app);
                if app.StrategySelect.Value == 1
                    addStrategy(app);
                end
    
                delete(app.WedgesEditField)
                delete(app.WedgesEditFieldLabel)
                delete(app.ConstraintsEditField)
                delete(app.ConstraintsEditFieldLabel)
                delete(app.StrategyEditField)
                delete(app.StrategyEditFieldLabel)
                delete(app.HistoricalDataEditField)
                delete(app.HistoricalDataEditFieldLabel)
                %delete(app.instructionLabel)
                delete(app.StrategySelect)
                delete(app.Lamp)
                delete(app.Lamp2)
                delete(app.Lamp2_2)
                delete(app.Lamp2_3)
                delete(app.SubmitButton)
                
            catch
                error = msgbox(sprintf('Invalid Filename: filepath %d',fail_p), 'Error','error');
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Callback function
        function StrategiesTypeBoxValueChanged(app, event)
            value = app.StrategiesTypeBox.Value;
            Selections = {'Efficiency & Conservation','Fossil-Fuel Reductions','Nuclear Energy','Biostorage & Renewables'};
            app.selectedWedges = value;
            
        end

        % Callback function: StrategySelect
        function StrategySelectValueChanged(app, event)
            checkboxBool = app.StrategySelect.Value;
            
        end

        % Value changed function: StrategiesListBox
        function StrategiesListBoxValueChanged(app, event)
            CurrWedgeStrat = app.StrategiesListBox.Value
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            
                selected = app.StrategiesListBox.Value
                s_index = find(strcmp(app.wedgeData.StrategyName,selected));
                %Default Start Year
                startYear = 2018;
                
                fields = fieldnames(app.selectedWedges)
                for i = 1:length(fields)-1
                    app.selectedWedges.(fields{i}){end+1} = app.wedgeData.(fields{i}){s_index}
                end
                app.selectedWedges.startYear(end+1) = startYear
                if ismember(selected,app.selectedStrategiesBox.Items)
                    counter = 1;
                    for i = 1:length(app.selectedStrategiesBox.Items)
                        try
                            if isequal(selected,app.selectedStrategiesBox.Items{i}(1:length(selected)))
                                counter = counter+1;
                            end
                        catch
                        end
                    end
                    selected = cat(2,selected,num2str(counter));
                end
                app.selectedStrategiesBox.Items = cat(2,app.selectedStrategiesBox.Items,selected)
                        
                %updateInfo(app); 
                plotWedges(app);
                checkConstraints(app);
                sumCost(app);
             
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            if ~isempty(app.selectedStrategiesBox.Value)
                selected = app.selectedStrategiesBox.Value
                s_index = find(strcmp(app.selectedStrategiesBox.Items,selected));
                current_vals = get(app.selectedStrategiesBox,'Items')
                current_vals(s_index) = []
                app.selectedStrategiesBox.Items = current_vals
                fields = fieldnames(app.selectedWedges)
                if length(app.selectedWedges.ID) == 1
                    for i = 1:length(fields)-1
                        app.selectedWedges.(fields{i}) = [] ;
                    end
                    app.selectedWedges.startYear = []
                    
                else
                    for i = 1:length(fields)-1
                        app.selectedWedges.(fields{i})(s_index) = [] ;
                    end
                    app.selectedWedges.startYear(s_index) = []
                end                                
                %updateInfo(app);  it breaks when selectedWedges is empty.
                plotWedges(app);
                sumCost(app);
                dispCurrMeta(app)
            end
        end

        % Button pushed function: ModifyStartButton
        function ModifyStartButtonPushed(app, event)
            year = app.StartYear.Value;
            if ~isempty(app.selectedStrategiesBox.Value) && year > 2017 && year < 2068
                selected = app.selectedStrategiesBox.Value
                s_index = find(strcmp(app.selectedStrategiesBox.Items,selected));
                app.selectedWedges.startYear(s_index) = year;
                plotWedges(app);
                dispCurrMeta(app);
            end
                       
        end

        % Callback function
        function ClearButtonPushed(app, event)
           
              %%fields = fieldnames(app.selectedWedges)
              %%app.selectedWedges.(fields{:})(:) = {};
              %%plotWedges(app)          %%-EVP- Is this a current state fxn call?, Using it later.
              %%checkConstraints(app)
              %%sumCost(app)
        end

        % Button pushed function: SaveCurrentStrategyButton
        function SaveCurrentStrategyButtonPushed(app, event)
            path = app.outputPath.Value
            writeWedges(app,path);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed2(app, event)
            app.selectedWedges = app.blankWedgeStruct
            app.selectedWedges.startYear = []
            app.selectedStrategiesBox.Items = {}
            plotWedges(app)
            sumCost(app)
        end

        % Button pushed function: SetLambdavalueButton
        function SetLambdavalueButtonPushed(app, event)
            if ~isempty(app.Lambda.Value)
            app.lambda = app.Lambda.Value
            plotWarming(app);
            end
        end

        % Value changed function: selectedStrategiesBox
        function selectedStrategiesBoxValueChanged(app, event)
            value = app.selectedStrategiesBox.Value;
            dispCurrMeta(app)
        end

        % Value changed function: flatCheck
        function flatCheckValueChanged(app, event)
            value = app.flatCheck.Value;
            if value == 1
            c_year = app.wedgeCalcYear.Value
            slope = (app.GtC_est(end)-(app.GtC_est(1)))./ 50;
            f =  @(x)  slope.*x - (slope*2018) + app.GtC_est(1);
            curr_GtC = f(c_year)
            final_GtC = f(2068);
            needed_redox = final_GtC - curr_GtC;
            needed_wedges = floor(needed_redox./((2068-c_year)./50));
            app.flatYear.Value = needed_wedges
            else
                app.flatYear.Value = 0;
            end
            
        end

        % Value changed function: nineCheck
        function nineCheckValueChanged(app, event)
            value = app.nineCheck.Value;
            GtCyr =cellfun(@str2double,app.historicalData.GtCyr)
            if value == 1
            c_year = app.wedgeCalcYear.Value
            slope = (app.GtC_est(end)-(app.GtC_est(1)))./ 50;
            f =  @(x)  slope.*x - (slope*2018) + app.GtC_est(1);
            curr_GtC = f(2068)
            final_GtC = GtCyr(37);
            needed_redox = curr_GtC - final_GtC;
            needed_wedges = floor(needed_redox./((2068-c_year)./50));
            app.nineYear.Value = needed_wedges
            else
                app.nineYear.Value = 0;
            end
        end

        % Value changed function: thirdCheck
        function thirdCheckValueChanged(app, event)
            value = app.thirdCheck.Value;
            GtCyr =cellfun(@str2double,app.historicalData.GtCyr)
            if value == 1
            c_year = app.wedgeCalcYear.Value
            slope = (app.GtC_est(end)-(app.GtC_est(1)))./ 50;
            f =  @(x)  slope.*x - (slope*2018) + app.GtC_est(1);
            curr_GtC = f(2068)
            final_GtC = GtCyr(37)*(.8);
            needed_redox = curr_GtC-final_GtC;
            needed_wedges = floor(needed_redox./((2068-c_year)./50));
            app.eightyYear.Value = needed_wedges
            else
                app.eightyYear.Value = 0;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.WedgesEditFieldLabel = uilabel(app.UIFigure);
            app.WedgesEditFieldLabel.Text = 'Wedges';
            app.WedgesEditField = uieditfield(app.UIFigure, 'text');
            app.WedgesEditField.ValueChangedFcn = createCallbackFcn(app, @WedgesEditFieldValueChanged, true);
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.ConstraintsEditFieldLabel = uilabel(app.UIFigure);
            app.ConstraintsEditFieldLabel.Text = 'Constraints';
            app.ConstraintsEditField = uieditfield(app.UIFigure, 'text');
            app.ConstraintsEditField.ValueChangedFcn = createCallbackFcn(app, @ConstraintsEditFieldValueChanged, true);
            app.StrategyEditFieldLabel = uilabel(app.UIFigure);
            app.StrategyEditFieldLabel.Text = 'Strategy';
            app.StrategyEditField = uieditfield(app.UIFigure, 'text');
            app.StrategyEditField.ValueChangedFcn = createCallbackFcn(app, @StrategyEditFieldValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp2 = uilamp(app.UIFigure);
            app.Lamp2_2 = uilamp(app.UIFigure);
            app.progressLabel = uilabel(app.UIFigure);
            app.progressLabel.Text = '1) Input Source files';
            app.TotalAvalibleWedgesPanel = uipanel(app.UIFigure);
            app.TotalAvalibleWedgesPanel.Title = 'Total Avalible Wedges';
            app.instructionLabel = uilabel(app.UIFigure);
            app.instructionLabel.Text = '*Please Enter names of the 3 or (4) files ';
            app.Lamp2_3 = uilamp(app.UIFigure);
            app.HistoricalDataEditFieldLabel = uilabel(app.UIFigure);
            app.HistoricalDataEditFieldLabel.Text = 'Historical Data';
            app.HistoricalDataEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentStateLabel = uilabel(app.UIFigure);
            app.CurrentStateLabel.Text = 'Current State:';
            app.StrategySelect = uicheckbox(app.UIFigure);
            app.StrategySelect.ValueChangedFcn = createCallbackFcn(app, @StrategySelectValueChanged, true);
            app.StrategySelect.Text = '';
            app.StrategiesListBox = uilistbox(app.UIFigure);
            app.StrategiesListBox.ValueChangedFcn = createCallbackFcn(app, @StrategiesListBoxValueChanged, true);
            app.selectedStrategiesBox = uilistbox(app.UIFigure);
            app.selectedStrategiesBox.ValueChangedFcn = createCallbackFcn(app, @selectedStrategiesBoxValueChanged, true);
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add ->';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = '<- Delete ';
            app.StrategiesLabel = uilabel(app.UIFigure);
            app.StrategiesLabel.Text = 'Strategies';
            app.SelectedStrategiesLabel = uilabel(app.UIFigure);
            app.SelectedStrategiesLabel.Text = 'Selected Strategies';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EmissionsPlotTab = uitab(app.TabGroup);
            app.EmissionsPlotTab.Title = 'Emissions Plot';
            app.MainPlot = uiaxes(app.EmissionsPlotTab);
            title(app.MainPlot, 'Gigatons of Carbon per Year')
            xlabel(app.MainPlot, 'Years')
            ylabel(app.MainPlot, 'GtC/Yr')
            app.MassofCO2PlotTab = uitab(app.TabGroup);
            app.MassofCO2PlotTab.Title = 'Mass of CO2 Plot';
            app.SecPlot = uiaxes(app.MassofCO2PlotTab);
            title(app.SecPlot, 'Mass Of Outputted CO2')
            xlabel(app.SecPlot, 'Years')
            ylabel(app.SecPlot, 'GtC')
            app.AverageWarmingPlotTab = uitab(app.TabGroup);
            app.AverageWarmingPlotTab.Title = 'Average Warming Plot';
            app.TerPlot = uiaxes(app.AverageWarmingPlotTab);
            title(app.TerPlot, 'Average Warming Of Earth')
            xlabel(app.TerPlot, 'Years')
            ylabel(app.TerPlot, 'Temperature Rise (degrees)')
            app.WhatHappensIfTab = uitab(app.TabGroup);
            app.WhatHappensIfTab.Title = 'What Happens If?';
            app.QuarPlot = uiaxes(app.WhatHappensIfTab);
            title(app.QuarPlot, 'Title')
            xlabel(app.QuarPlot, 'Year')
            app.ConstraintsPanel = uipanel(app.UIFigure);
            app.ConstraintsPanel.Title = 'Active Constraints';
            app.ModifyStartButton = uibutton(app.UIFigure, 'push');
            app.ModifyStartButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyStartButtonPushed, true);
            app.ModifyStartButton.Text = 'Modify Start';
            app.StartYear = uieditfield(app.UIFigure, 'numeric');
            app.TotalCostofStrategyLabel = uilabel(app.UIFigure);
            app.TotalCostofStrategyLabel.Text = 'Total Cost of Strategy';
            app.Cost = uieditfield(app.UIFigure, 'numeric');
            app.Cost.Editable = 'off';
            app.SaveCurrentStrategyButton = uibutton(app.UIFigure, 'push');
            app.SaveCurrentStrategyButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCurrentStrategyButtonPushed, true);
            app.SaveCurrentStrategyButton.Text = 'Save Current Strategy';
            app.outputPath = uieditfield(app.UIFigure, 'text');
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed2, true);
            app.ClearButton.Text = 'Clear';
            app.Lambda = uieditfield(app.UIFigure, 'numeric');
            app.SetLambdavalueButton = uibutton(app.UIFigure, 'push');
            app.SetLambdavalueButton.ButtonPushedFcn = createCallbackFcn(app, @SetLambdavalueButtonPushed, true);
            app.SetLambdavalueButton.Text = 'Set Lambda value';
            app.CurrentWedgePanel = uipanel(app.UIFigure);
            app.CurrentWedgePanel.Title = 'Current Wedge';
            app.DescriptionLabel = uilabel(app.CurrentWedgePanel);
            app.DescriptionLabel.Text = 'Description';
            app.NeedsLabel = uilabel(app.CurrentWedgePanel);
            app.NeedsLabel.Text = 'Needs';
            app.ChallengesLabel = uilabel(app.CurrentWedgePanel);
            app.ChallengesLabel.Text = 'Challenges:';
            app.currDescription = uilabel(app.CurrentWedgePanel);
            app.currDescription.Text = '';
            app.currNeeds = uilabel(app.CurrentWedgePanel);
            app.currNeeds.Text = '';
            app.currChallenges = uilabel(app.CurrentWedgePanel);
            app.currChallenges.Text = '';
            app.StartYearLabel = uilabel(app.CurrentWedgePanel);
            app.StartYearLabel.Text = 'Start Year';
            app.currStart = uilabel(app.CurrentWedgePanel);
            app.currStart.Text = '';
            app.WedgesNescesaryToPanel = uipanel(app.UIFigure);
            app.WedgesNescesaryToPanel.Title = 'Wedges Nescesary To...';
            app.FlatineemmissionsLabel = uilabel(app.WedgesNescesaryToPanel);
            app.FlatineemmissionsLabel.Text = 'Flatine emmissions: ';
            app.ReduceemissionstoLabel = uilabel(app.WedgesNescesaryToPanel);
            app.ReduceemissionstoLabel.Text = 'Reduce emissions to ';
            app.ReduceemissionstoLabel_2 = uilabel(app.WedgesNescesaryToPanel);
            app.ReduceemissionstoLabel_2.Text = 'Reduce emissions to ';
            app.IfstartedintheyearLabel = uilabel(app.WedgesNescesaryToPanel);
            app.IfstartedintheyearLabel.Text = 'If started in the year:';
            app.wedgeCalcYear = uieditfield(app.WedgesNescesaryToPanel, 'numeric');
            app.wedgeCalcYear.Limits = [2018 2067];
            app.wedgeCalcYear.Value = 2018;
            app.slevelsLabel = uilabel(app.WedgesNescesaryToPanel);
            app.slevelsLabel.Text = ' 1990s levels:';
            app.flatCheck = uicheckbox(app.WedgesNescesaryToPanel);
            app.flatCheck.ValueChangedFcn = createCallbackFcn(app, @flatCheckValueChanged, true);
            app.flatCheck.Text = '';
            app.nineCheck = uicheckbox(app.WedgesNescesaryToPanel);
            app.nineCheck.ValueChangedFcn = createCallbackFcn(app, @nineCheckValueChanged, true);
            app.nineCheck.Text = '';
            app.of1990slevelsLabel = uilabel(app.WedgesNescesaryToPanel);
            app.of1990slevelsLabel.Text = '80% of 1990s levels:';
            app.thirdCheck = uicheckbox(app.WedgesNescesaryToPanel);
            app.thirdCheck.ValueChangedFcn = createCallbackFcn(app, @thirdCheckValueChanged, true);
            app.thirdCheck.Text = '';
            app.flatYear = uieditfield(app.WedgesNescesaryToPanel, 'numeric');
            app.flatYear.Editable = 'off';
            app.nineYear = uieditfield(app.WedgesNescesaryToPanel, 'numeric');
            app.nineYear.Editable = 'off';
            app.eightyYear = uieditfield(app.WedgesNescesaryToPanel, 'numeric');
            app.eightyYear.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = climatewedges

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end